classdef HlasoveOvladani < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        StartButton     matlab.ui.control.Button
        SignalAxes      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        CommandList;
        Robot
        Fs;
        threshold;
        VoiceRecorder;
        TrainedModel; % Description
        IsRunning;
    end

    methods (Access = private)

        function updatePlot(app, audioData, titleText)
            if isa(audioData, 'int16')
                % Data z mikrofonu -> převedeme na double a zmenšíme
                plotData = double(audioData) / 32768.0;
            else
                % Data ze souboru -> už jsou správně, použijeme jak jsou
                plotData = audioData;
            end

            % 2. Vytvoření časové osy
            t = (0:length(plotData)-1) / app.Fs;

            % 3. Vykreslení
            plot(app.SignalAxes, t, plotData);
            title(app.SignalAxes, titleText); % Použijeme název předaný v závorce
            xlabel(app.SignalAxes, 'Čas (s)');
            grid(app.SignalAxes, 'on');
            ylim(app.SignalAxes, [-1.1 1.1]);
        end


        function word = readHTKResult(~, filePath)
            word = 'NEROZPOZNÁNO';
            if exist(filePath, 'file')
                text = fileread(filePath);

                % Rozsekáme celý textový soubor na jednotlivé "bloky" (oddělené mezerou/novým řádkem)
                tokens = regexp(text, '\s+', 'split');

                % Najdeme pozici slova 'SENT-START'
                idx = find(strcmp(tokens, 'SENT-START'), 1);

                if ~isempty(idx)
                    % Procházíme všechno, co je za SENT-START
                    for i = idx+1 : length(tokens)
                        tok = tokens{i};

                        % Pokud je to číslo (časy, skóre), tak ho přeskočíme
                        if ~isnan(str2double(tok))
                            continue;
                        end

                        % Pokud narazíme na konec věty nebo tečku, konec hledání
                        if strcmp(tok, 'SENT-END') || strcmp(tok, '.')
                            break;
                        end

                        % Našli jsme něco, co není číslo ani konec! To je naše slovo!
                        word = tok;
                        break;
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import java.awt.Robot;
            import java.awt.event.KeyEvent;
            app.Robot = java.awt.Robot();

            %Vars
            app.Fs = 16000;
            app.threshold=0.08;

            %Objects
            app.VoiceRecorder = audiorecorder(app.Fs, 16, 1, -1);
        end

        % Button pushed function: StartButton
        function StartButtonButtonPushed(app, event)
            import java.awt.event.KeyEvent;

            % Přepínání stavu (Start / Stop)
            if app.StartButton.Text == "Spustit hlasové ovládání"

                app.StartButton.Text = "ZASTAVIT";
                app.StartButton.BackgroundColor = [1, 0, 0]; % Červená
                app.IsRunning = true;

                % Inicializace robota pro mačkání kláves
                vRobot = app.Robot();
                vRobot.setAutoDelay(50);

                app.EditField.Value = "Klikni do Kalkulačky!";
                drawnow;

                pause(3); 
                app.EditField.Value = "Poslouchám...";
                drawnow;
                disp("Hlasové ovládání spuštěno. Poslouchám...");

                % Vytvoření konfiguračního souboru pro HCopy (stačí vytvořit jednou)
                fid = fopen('live.list', 'w');
                fprintf(fid, 'live_input.wav live_input.mfc\n');
                fclose(fid);

                while app.IsRunning
                    % Nahráváme krátké okno (např. 1.2 sekundy)
                    recordblocking(app.VoiceRecorder, 1.2);

                    if ~app.IsRunning
                        break;
                    end

                    audioIn = getaudiodata(app.VoiceRecorder);
                    % signalEnergy = rms(audioIn);

                    % Vykreslení signálu do grafu ---
                    app.updatePlot(audioIn, 'Aktuální nahrávka');
                    drawnow;

                    peakVolume = max(abs(audioIn));
                    if peakVolume < app.threshold
                        app.EditField.Value = "Ticho...";
                        app.EditField.FontColor = [0.5 0.5 0.5];
                        drawnow;
                        continue;
                    end

                    app.EditField.Value = "Zpracovávám...";
                    drawnow;

                    % Normalizace a uložení
                    audioIn = audioIn / max(abs(audioIn));
                    audiowrite('live_input.wav', audioIn, app.Fs);

                    % Smazání starého výsledeku
                    if exist('live_res.mlf', 'file')
                        delete('live_res.mlf');
                    end

                    % Ošetřené volání HTK
                    [stat1, out1] = system('HCopy -T 1 -C ParamConfig-MFCC39 -S live.list');
                    if stat1 ~= 0
                        disp('CHYBA V HCopy (Parametrizace):'); disp(out1);
                    end

                    [stat2, out2] = system('HVite -H hmm3/macros -H hmm3/hmmdefs -i live_res.mlf -w net -p 0.0 -s 5.0 dict models0 live_input.mfc');
                    if stat2 ~= 0
                        disp('CHYBA V HVite (Rozpoznávání):'); disp(out2);
                    end

                    % Přečtení výsledku z HTK
                    rozpoznaneSlovo = app.readHTKResult('live_res.mlf');
                    fprintf('Rozpoznáno: %s\n', rozpoznaneSlovo);

                    app.EditField.Value = string(rozpoznaneSlovo);
                    app.EditField.FontColor = [0 0.5 0];

                    % 4. Provedení akce - ZMÁČKNUTÍ KLÁVESY
                    switch upper(rozpoznaneSlovo)
                        case 'JEDNA'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD1);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD1);
                        case 'DVA'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD2);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD2);
                        case 'TRI'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD3);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD3);
                        case 'CTYRI'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD4);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD4);
                        case 'PET'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD5);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD5);
                        case 'SEST'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD6);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD6);
                        case 'SEDM'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD7);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD7);
                        case 'OSM'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD8);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD8);
                        case 'DEVET'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD9);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD9);
                        case 'NULA'
                            vRobot.keyPress(KeyEvent.VK_NUMPAD0);
                            vRobot.keyRelease(KeyEvent.VK_NUMPAD0);
                        case 'PLUS'
                            vRobot.keyPress(KeyEvent.VK_ADD);
                            vRobot.keyRelease(KeyEvent.VK_ADD);
                        case 'MINUS'
                            vRobot.keyPress(KeyEvent.VK_SUBTRACT);
                            vRobot.keyRelease(KeyEvent.VK_SUBTRACT);
                        case 'KRAT'
                            vRobot.keyPress(KeyEvent.VK_MULTIPLY);
                            vRobot.keyRelease(KeyEvent.VK_MULTIPLY);
                        case 'DELENO'
                            vRobot.keyPress(KeyEvent.VK_DIVIDE);
                            vRobot.keyRelease(KeyEvent.VK_DIVIDE);
                        case 'ROVNASE'
                            vRobot.keyPress(KeyEvent.VK_ENTER);
                            vRobot.keyRelease(KeyEvent.VK_ENTER);
                        case 'SMAZAT'
                            vRobot.keyPress(KeyEvent.VK_BACK_SPACE);
                            vRobot.keyRelease(KeyEvent.VK_BACK_SPACE);
                        case 'VYMAZAT'
                            vRobot.keyPress(KeyEvent.VK_ESCAPE);
                            vRobot.keyRelease(KeyEvent.VK_ESCAPE); % Escape maže celou kalkulačku
                        case 'PLUSMINUS'
                            % Klávesová zkratka pro +/- ve Win kalkulačce je obvykle F9
                            vRobot.keyPress(KeyEvent.VK_F9);
                            vRobot.keyRelease(KeyEvent.VK_F9);
                        case 'CARKA'
                            % Desetinná čárka
                            vRobot.keyPress(KeyEvent.VK_COMMA);
                            vRobot.keyRelease(KeyEvent.VK_COMMA);
                        case 'NADRUHOU'
                            % Klávesová zkratka je 'Q'
                            vRobot.keyPress(KeyEvent.VK_Q);
                            vRobot.keyRelease(KeyEvent.VK_Q);
                        case 'ODMOCNINA'
                            % Klávesová zkratka je '@'
                            vRobot.keyPress(KeyEvent.VK_CONTROL);
                            vRobot.keyPress(KeyEvent.VK_ALT);
                            vRobot.keyPress(KeyEvent.VK_V);
                            
                            vRobot.keyRelease(KeyEvent.VK_V);
                            vRobot.keyRelease(KeyEvent.VK_ALT);
                            vRobot.keyRelease(KeyEvent.VK_CONTROL);                        
                        case 'ZPET'
                            % Pokud to znamená krok zpět
                            vRobot.keyPress(KeyEvent.VK_BACK_SPACE);
                            vRobot.keyRelease(KeyEvent.VK_BACK_SPACE);
                    end

                    % Malá pauza, aby se nestisklo více kláves naráz
                    pause(0.2);
                    drawnow;
                end
            else
                % ZASTAVENÍ
                app.IsRunning = false;
                app.StartButton.Text = "Spustit hlasové ovládání";
                app.StartButton.BackgroundColor = [0.96, 0.96, 0.96];
                disp("Hlasové ovládání zastaveno.");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SignalAxes = uiaxes(app.UIFigure);
            title(app.SignalAxes, 'Title')
            xlabel(app.SignalAxes, 'Čas(s)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonButtonPushed, true);
            app.StartButton.Text = 'Spustit hlasové ovládání';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HlasoveOvladani

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end